classdef Auraliza < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InputsPanel                 matlab.ui.container.Panel
        SOFApath                    matlab.ui.control.EditField
        AUDIOpath                   matlab.ui.control.EditField
        HRTFSOFAButton              matlab.ui.control.StateButton
        AudioWAVButton              matlab.ui.control.StateButton
        OptionalInputsPanel         matlab.ui.container.Panel
        opt_IRpath                  matlab.ui.control.EditField
        IRWAVButton                 matlab.ui.control.StateButton
        XButton                     matlab.ui.control.Button
        PlayButton                  matlab.ui.control.StateButton
        PauseButton                 matlab.ui.control.StateButton
        BufferSizeLabel             matlab.ui.control.Label
        BufferSize                  matlab.ui.control.NumericEditField
        StereoRenderButton          matlab.ui.control.StateButton
        InterpolationDropDownLabel  matlab.ui.control.Label
        InterpolationDropDown       matlab.ui.control.DropDown
        Label                       matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Obj_HRTF     % SOFA HRTF file
        posi        % posições de fonte 
        fs_SOFA     % sample rate SOFA
        fs_Audio   % sample rate Audio 
        Audio      % Audio file input
        deviceWriter  %dispositivo de reprodução
        dspHRIR_L   %HRIR esquerda para a posição selecionada
        dspHRIR_R   %HRIR esquerda para a posição selecionada
        NameAudio  % nome no Audio sem extensão
        Audiofolder % folder path 
        opt_IR      % input IR opcional
        fs_opt_IR   % sample rate da IR opcional
        no_input   % numero de canais de entrada 
        flag_opt_IR % avisa se ha input
        ROI         %região de interesse (posição da fonte)
        ROI_flag    % avisa se ROI foi inicializado ou não
        Obj_RIR     % RIR/DRIR SOFA
        convAudio   % audio convoluido com resposta impulsiva
        flag_interp % flag de erro de interpolação
        mouse_state % estado atual do mouse dentro do plot
        Tag         % Objeto com informações de cada canal
    end
        
    methods (Access = private)               
       function HRIR_filter_design(app, azim, elev, ch)     
            % Por interpolação
            if any(strcmp(app.InterpolationDropDown.Value, {'VBAP', 'Bilinear'}))                
                    % miinterolateHRTF possui pequenas correções na função interna do matlab
                    interpolatedIR = miinterpolateHRTF(shiftdim(app.Obj_HRTF.Data.IR,1),...
                                                     app.posi(:,[1,2]),[azim, elev],...
                                                     'Algorithm', app.InterpolationDropDown.Value);   
                    app.dspHRIR_L.(ch) = squeeze(interpolatedIR(:,1,:)).';
                    app.dspHRIR_R.(ch) = squeeze(interpolatedIR(:,2,:)).'; 
                    % Caso erro na interpolação vai sem interpolação
                    if any(isnan(interpolatedIR(:)))
                        app.flag_interp = 'Empty';                        
                    end
                    
            % Pela posição mais próxima
            elseif any(strcmp(app.InterpolationDropDown.Value, 'Nearest')) ||...
                   any(strcmp(app.flag_interp, 'Empty'))
                % Pela posição mais próxima
                    [~,pos_idx] = min(sqrt((app.posi(:,1) - azim).^2 + (app.posi(:,2) - elev).^2));
        
                    app.dspHRIR_L.(ch) = app.Obj_HRTF.Data.IR(:, pos_idx,1).';
                    app.dspHRIR_R.(ch) = app.Obj_HRTF.Data.IR(:, pos_idx,2).';   
                    
                    if strcmp(app.flag_interp, {'VBAP', 'Bilinear'})
                        % Reseta flag em interpolação tenha dado errado
                        app.flag_interp = ''; % util quando bilinear disponível
                    end
            end
        end
       
        
        function allevents(app, src, ~)
            HRIR_filter_design(app, src.Position(1), src.Position(2), src.Tag)
        end
        
%         function EAC_listener(app)
%             % set function to call on mouse click
%             set(app.UIAxes,'WindowButtonMotionFcn',@mouse_mov);
%             set(app.UIAxes,'WindowButtonDownFcn',@mouse_down);
%             set(app.UIAxes,'WindowButtonUpFcn',@mouse_up);
%             
%             
%             % function called on mouse click in the figure
%             function mouse_down(~,~)                 
%                 app.mouse_state = true;
%                 temp = get(gca, 'CurrentPoint');
%                 app.Tag.azim = temp(1);
%                 app.Tag.elev = temp(3);
%             end
%             
%             function mouse_up(~,~)
%                 app.mouse_state = false;
%             end
%             
%             function mouse_mov(~,~)
%                 while app.mouse_state            
%                     temp = get(gca, 'CurrentPoint');
%                     app.Tag.azim = temp(1);
%                     app.Tag.elev = temp(3);
%                     pause(0)
%                  end
%             end 
%             
%             % get corresponding HRIR
%             HRIR_filter_design(app, app.Tag.azim, app.Tag.elev, app.Tag.name)
            
            % draw corresponding point
%         end
        
        function optional_IR_conv(app)
            wait = waitbar(0, 'Processing Impulse Responses', 'Name','Wait');
            release(app.Audio)                         
            release(app.deviceWriter)
            [aud, ~] = audioread(fullfile(app.Audiofolder, app.NameAudio));
            nfft = length(aud) + length(app.opt_IR) - 1; 
            audFreq  = fft(aud, nfft);
            waitbar(0.4, wait)
            opt_IR_FREQ = fft(app.opt_IR, nfft);
            waitbar(0.8, wait)
            convL = real(ifft(audFreq .* opt_IR_FREQ(:,1), nfft));
            convR = real(ifft(audFreq .* opt_IR_FREQ(:,2), nfft));
            app.convAudio = [convL, convR];
            app.Audio = dsp.SignalSource(app.convAudio); 
            close(wait)                                      
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,'center')
            set(app.UIAxes,'Color',[0.1,0.1,0.15]) % fundo preto
            % Desabilitar iteratividade do plot
            disableDefaultInteractivity(app.UIAxes); 
            app.UIAxes.Toolbar.Visible = 'off'; 
            set(app.UIAxes,'XColor',[0.5 0.5 0.5]); % tick label colors
            set(app.UIAxes,'YColor',[0.5 0.5 0.5]); % tick label colors
            set(app.UIAxes, 'FontSize', 10)
            app.flag_opt_IR = 0;
            app.ROI_flag = 1;
            app.flag_interp = '';
        end

        % Value changed function: HRTFSOFAButton
        function HRTFSOFAButtonValueChanged(app, event)
            [baseName, folder] = uigetfile([pwd, filesep, '*.sofa'], 'Load SOFA HRTF:');
            app.SOFApath.Value = baseName;  
            figure(app.UIFigure) %keep focus on app window

            % LOAD SOFA
            app.Obj_HRTF = SOFAload(fullfile(folder, baseName), 'nochecks');
            app.posi = app.Obj_HRTF.SourcePosition;
            [app.posi(:,1), app.posi(:,2)] = sph2nav(app.posi(:,1),app.posi(:,2));
            app.posi(:,1) = - app.posi(:,1);
            app.fs_SOFA = app.Obj_HRTF.Data.SamplingRate;
            app.Obj_HRTF.Data.IR = shiftdim(app.Obj_HRTF.Data.IR./max(abs(app.Obj_HRTF.Data.IR(:))), 2); 
            
            % General flags
            if app.no_input >= 1 % Verifica se audio já foi inserido
                app.PlayButton.Enable = true;                   
                app.PauseButton.Enable = true;
                app.ROI_flag = 1;
            end          
        end

        % Value changed function: AudioWAVButton
        function AudioWAVButtonValueChanged(app, event)
            [NameAud, Audfolder] = uigetfile([pwd, filesep, '*.wav'], 'Load Audio File:');
            app.NameAudio = NameAud; %nomeie fora da função, caso o usuario feche a janela sem selecionar arquivo
            app.Audiofolder = Audfolder;
            
            figure(app.UIFigure) %keep focus on the app window
            app.AUDIOpath.Value = app.NameAudio;            
            
            frameLength = app.BufferSize.Value; %make this a user defined option
            app.Audio = dsp.AudioFileReader(fullfile(app.Audiofolder, app.NameAudio), ...
                'SamplesPerFrame', frameLength);
            app.fs_Audio = app.Audio.SampleRate;      
            app.deviceWriter = audioDeviceWriter( ...
                                    'SampleRate', app.fs_Audio); 
            app.no_input = size(app.Audio(), 2);
            
            % General flags
            if isfield(app.Obj_HRTF, 'Data') % Verifica se HRTFs já foram inseridas
                app.PlayButton.Enable = true;                   
                app.PauseButton.Enable = true;
                app.ROI_flag = 1;
            end
      
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            app.PlayButton.Value = 0;
            app.PlayButton.Enable = false;
            app.HRTFSOFAButton.Enable = false;
            app.AudioWAVButton.Enable = false;
            app.IRWAVButton.Enable = false;
            app.StereoRenderButton.Enable = false;
            app.XButton.Enable = false;
            
            % only allow auralize if Sample Rate matchs
            if app.fs_Audio ~= app.fs_SOFA 
                warndlg(['TAXA DE AMOSTRAGEM: HRTFs estão em ', ...
                    num2str(app.fs_SOFA/1000) 'kHz, mas Audio está em ', ...
                    num2str(app.fs_Audio/1000) 'kHz'], 'Erro');
            else 
                
                
                if app.ROI_flag % caso precise redesenhar
                   %%%% PLOT positions
                    hold(app.UIAxes, 'off');                    
                    scatter(app.UIAxes, app.posi(:,1), app.posi(:,2), 27,... 
                            'square', 'filled', 'MarkerFaceColor',[0.5, 0.47, 0.53]);                                          
                    hold(app.UIAxes, 'on');            
                    axis(app.UIAxes, 'tight');    
                    
                    % Fazer convolução IR 
                    if app.flag_opt_IR
                        if app.fs_opt_IR == app.fs_SOFA
                            if size(app.opt_IR, 2) < 2                               
                                app.opt_IR(:,2) = app.opt_IR(:,1);                                                                                                                             
                            end
                            % IR CONVOLUTION  
                            optional_IR_conv(app)
                        else 
                            warndlg(['TAXA DE AMOSTRAGEM: HRTFs estão em ', ...
                            num2str(app.fs_SOFA/1000) 'kHz, mas IR está em ', ...
                            num2str(app.fs_opt_IR/1000) 'kHz'], 'Erro');
                        end
                    end
                end        
                            
                %> Initialize filters
                azim = -10;
                for k = 1:app.no_input       
                    FIR_L{k} = dsp.FIRFilter('NumeratorSource','Input port');
                    FIR_R{k} = dsp.FIRFilter('NumeratorSource','Input port');  
                    roi_name = (['CH' num2str(k)]);
                    
                    
                    if app.ROI_flag % evita reinicializar o ROI e outras coisas a cada play
                        azim = azim+10; elev = 0;
                        app.ROI.(roi_name) = drawpoint(app.UIAxes, 'Position',[azim, elev],...
                                                                        'color', 'y');
                        app.ROI.(roi_name).Tag = (roi_name);
                        HRIR_filter_design(app, azim, elev, roi_name);  
                        addlistener(app.ROI.(roi_name), 'ROIMoved', @app.allevents);
                        app.ROI.(roi_name).Label = roi_name;                     
                    end
                end
                app.ROI_flag = 0;                
                
                
                % Play Obj_HRTFect setup
                release(app.Audio)
                app.BufferSize.Value = 2^nextpow2(app.BufferSize.Value);
                app.Audio.SamplesPerFrame = app.BufferSize.Value;
                setup(app.deviceWriter,zeros(app.Audio.SamplesPerFrame, app.no_input))
                
                %%% PLAY AND PROCESS AUDIO %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               
                while ~isDone(app.Audio)   
                    pause(0); % permite a movimentação do cursor  
                    
                    % Check if pause button action
                    if app.PauseButton.Value
                       release(app.Audio)                         
                       release(app.deviceWriter)
                       app.PauseButton.Value = 0;
                       app.PlayButton.Enable = true;
                       app.HRTFSOFAButton.Enable = true;
                       app.AudioWAVButton.Enable = true;
                       app.IRWAVButton.Enable = true;
                       app.StereoRenderButton.Enable = true; 
                       if app.flag_opt_IR
                          app.XButton.Enable = true;
                       end
                       break
                    end
                    audioIn = app.Audio(); 

                     
                     % Apply HRTFs
                    for k = 1:app.no_input
                        roi_name = (['CH' num2str(k)]);
                        % HRTFs        
                        if app.flag_opt_IR % Caso IR opcional inserida
                            out_temp_L(:,k) = FIR_L{k}(audioIn(:,k), app.dspHRIR_L.(roi_name)); % Left
                            out_temp_R(:,k) = FIR_R{k}(audioIn(:,k+app.no_input), app.dspHRIR_R.(roi_name)); % Right
                        else
                            out_temp_L(:,k) = FIR_L{k}(audioIn(:,k), app.dspHRIR_L.(roi_name)); % Left
                            out_temp_R(:,k) = FIR_R{k}(audioIn(:,k), app.dspHRIR_R.(roi_name)); % Right                        
                        end
                    end
                    app.deviceWriter(0.8*[mean(out_temp_L,2) mean(out_temp_R,2)]);         
             
                end                      
                release(app.Audio)                         
                release(app.deviceWriter)   
                app.PlayButton.Enable = true;
                app.HRTFSOFAButton.Enable = true;
                app.AudioWAVButton.Enable = true;
                app.IRWAVButton.Enable = true;
                app.StereoRenderButton.Enable = true; 
                if app.flag_opt_IR
                   app.XButton.Enable = true;
                end
            end           
        end

        % Value changed function: IRWAVButton
        function IRWAVButtonValueChanged(app, event)
            [IR_name, folder] = uigetfile([pwd, filesep, '*.wav'], 'Load Impulse Response:');
            figure(app.UIFigure) %keep focus on the app window
            [temp_ir, app.fs_opt_IR] = audioread(fullfile(folder, IR_name));
            % Suporte para no máximo dois canais
            if size(temp_ir, 2) > 2
                 warndlg('Número de canais não suportado, máximo 2 canais', 'Erro');
            else
                app.opt_IR = (temp_ir./max(abs(temp_ir(:))))*0.98;
                app.opt_IRpath.Value = IR_name;
                app.flag_opt_IR = 1;
                app.ROI_flag = 1;                
                app.XButton.Enable = true;              
            end          
        end

        % Value changed function: StereoRenderButton
        function StereoRenderButtonValueChanged(app, event)
            release(app.Audio)                         
            release(app.deviceWriter)   
            app.StereoRenderButton.Enable = false;
            app.PlayButton.Enable = false;
            app.PauseButton.Enable = false;
            app.HRTFSOFAButton.Enable = false;
            app.AudioWAVButton.Enable = false;
            app.IRWAVButton.Enable = false;
            app.XButton.Enable = false;
            
            wait = waitbar(0, 'Processing output...', 'Name','Rendering:');           
            if ~app.flag_opt_IR % Load audio again
                [aud, ~] = audioread(fullfile(app.Audiofolder, app.NameAudio));
            else
                optional_IR_conv(app) % make sure IR is convoluted
            end
            
            for k = 1:app.no_input    
                roi_name = (['CH' num2str(k)]);
                waitbar(k/app.no_input, wait)
                % Convolução saída        
                if app.flag_opt_IR % Caso IR opcional inserida                   
                    nfft = length(app.convAudio(:,k)) + length(app.dspHRIR_L.(roi_name)) - 1;
                    out_render_L(:,k) = real(ifft(fft(app.convAudio(:,k), nfft).* fft(app.dspHRIR_L.(roi_name).', nfft), nfft)); % Left
                    out_render_R(:,k) = real(ifft(fft(app.convAudio(:,k+app.no_input), nfft).* fft(app.dspHRIR_R.(roi_name).', nfft), nfft)); % Right
                else
                    nfft = length(aud(:,k)) + length(app.dspHRIR_L.(roi_name)) - 1;
                    out_render_L(:,k) = ifft(fft(aud(:,k),nfft).* fft(app.dspHRIR_L.(roi_name).',nfft),nfft); % Left
                    out_render_R(:,k) = ifft(fft(aud(:,k),nfft).* fft(app.dspHRIR_R.(roi_name).',nfft),nfft); % Right
                end
                waitbar((k/app.no_input + k/(app.no_input*2)), wait)
            end 
                      
            y = [mean(out_render_L,2) mean(out_render_R,2)];  
            y = y./max(abs(y(:)))*0.98;
            % Ask for save path and name        
            [~, baseFileNameNoExt, ~] = fileparts([app.Audiofolder  app.NameAudio ]);
            [file,path,~] = uiputfile([baseFileNameNoExt, '_EAC.wav']);
            figure(app.UIFigure) %keep focus on the app window
            audiowrite([path file], y, app.fs_Audio)
            waitbar(1, wait); pause(1); close(wait)
            
            app.StereoRenderButton.Enable = true;
            app.PlayButton.Enable = true;
            app.PauseButton.Enable = true;
            app.HRTFSOFAButton.Enable = true;
            app.AudioWAVButton.Enable = true;
            app.IRWAVButton.Enable = true;   
            app.XButton.Enable = true;
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.fs_opt_IR = [];
            app.opt_IR = [];
            app.opt_IRpath.Value = '';
            app.flag_opt_IR = 0;
            app.ROI_flag = 1;
            % Remover convolução
            release(app.Audio)                         
            release(app.deviceWriter)
            [aud, ~] = audioread(fullfile(app.Audiofolder, app.NameAudio));
            app.Audio = dsp.SignalSource(aud); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.SOFApath = uieditfield(app.InputsPanel, 'text');
            app.SOFApath.Editable = 'off';
            app.AUDIOpath = uieditfield(app.InputsPanel, 'text');
            app.AUDIOpath.Editable = 'off';
            app.HRTFSOFAButton = uibutton(app.InputsPanel, 'state');
            app.HRTFSOFAButton.ValueChangedFcn = createCallbackFcn(app, @HRTFSOFAButtonValueChanged, true);
            app.HRTFSOFAButton.Text = 'HRTF (.SOFA)';
            app.AudioWAVButton = uibutton(app.InputsPanel, 'state');
            app.AudioWAVButton.ValueChangedFcn = createCallbackFcn(app, @AudioWAVButtonValueChanged, true);
            app.AudioWAVButton.Text = 'Audio (.WAV)';
            app.OptionalInputsPanel = uipanel(app.UIFigure);
            app.OptionalInputsPanel.Title = 'Optional Inputs';
            app.opt_IRpath = uieditfield(app.OptionalInputsPanel, 'text');
            app.opt_IRpath.Editable = 'off';
            app.IRWAVButton = uibutton(app.OptionalInputsPanel, 'state');
            app.IRWAVButton.ValueChangedFcn = createCallbackFcn(app, @IRWAVButtonValueChanged, true);
            app.IRWAVButton.Text = 'IR (.WAV)';
            app.XButton = uibutton(app.OptionalInputsPanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Enable = 'off';
            app.XButton.Text = 'X';
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Icon = 'playbuttom.png';
            app.PlayButton.Text = {''; ''};
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.Enable = 'off';
            app.PauseButton.Icon = 'stopbuttom.png';
            app.PauseButton.Text = '';
            app.BufferSizeLabel = uilabel(app.UIFigure);
            app.BufferSizeLabel.Text = 'Buffer Size';
            app.BufferSize = uieditfield(app.UIFigure, 'numeric');
            app.BufferSize.Value = 1024;
            app.StereoRenderButton = uibutton(app.UIFigure, 'state');
            app.StereoRenderButton.ValueChangedFcn = createCallbackFcn(app, @StereoRenderButtonValueChanged, true);
            app.StereoRenderButton.Enable = 'off';
            app.StereoRenderButton.Text = 'Stereo Render';
            app.InterpolationDropDownLabel = uilabel(app.UIFigure);
            app.InterpolationDropDownLabel.Text = 'Interpolation:';
            app.InterpolationDropDown = uidropdown(app.UIFigure);
            app.InterpolationDropDown.Items = {'Nearest', 'VBAP'};
            app.InterpolationDropDown.Value = 'Nearest';
            app.Label = uilabel(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Auraliza

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end